classdef blqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        blqAnalysisUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        InfoStructtoworkspaceMenu       matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        RecalculateFFTMenu              matlab.ui.container.Menu
        RemoveCentralLineMenu           matlab.ui.container.Menu
        VerticalMenu                    matlab.ui.container.Menu
        HorizontalMenu                  matlab.ui.container.Menu
        VerticalHorizontalMenu          matlab.ui.container.Menu
        SymmetrizeMenu                  matlab.ui.container.Menu
        VerticalmirrorMenu              matlab.ui.container.Menu
        VerticalHorizontalmirrorMenu    matlab.ui.container.Menu
        C4symmetryMenu                  matlab.ui.container.Menu
        C6symmetryMenu                  matlab.ui.container.Menu
        C3symmetryMenu                  matlab.ui.container.Menu
        CellMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OldMenu                         matlab.ui.container.Menu
        SaveallMenu                     matlab.ui.container.Menu
        NotitlenoaxesMenu               matlab.ui.container.Menu
        OnlytitleMenu                   matlab.ui.container.Menu
        TitleandaxesMenu                matlab.ui.container.Menu
        SaveformatMenu                  matlab.ui.container.Menu
        jpegMenu                        matlab.ui.container.Menu
        pngMenu                         matlab.ui.container.Menu
        svgMenu                         matlab.ui.container.Menu
        MagneticFieldValue              matlab.ui.control.Label
        TemperatureValue                matlab.ui.control.Label
        MagneticFieldLabel              matlab.ui.control.Label
        TemperatureLabel                matlab.ui.control.Label
        blqAnalysisQPIStudyLabel        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        OpenTab                         matlab.ui.container.Tab
        CurrentblqName                  matlab.ui.control.Label
        CurrentInfoStructName           matlab.ui.control.Label
        CurrentblqLabel                 matlab.ui.control.Label
        CurrentInfoStructLabel          matlab.ui.control.Label
        GeneratemapsButton              matlab.ui.control.Button
        SinglecurvesButton              matlab.ui.control.Button
        RestoreButton                   matlab.ui.control.Button
        y2EditField                     matlab.ui.control.NumericEditField
        y2EditFieldLabel                matlab.ui.control.Label
        y1EditField                     matlab.ui.control.NumericEditField
        y1EditFieldLabel                matlab.ui.control.Label
        x2EditField                     matlab.ui.control.NumericEditField
        x2EditFieldLabel                matlab.ui.control.Label
        x1EditField                     matlab.ui.control.NumericEditField
        x1EditFieldLabel                matlab.ui.control.Label
        CutInfoStructButton             matlab.ui.control.Button
        LoadInfoStructfromworkspaceButton  matlab.ui.control.Button
        NewAppButton                    matlab.ui.control.Button
        ClearallButton                  matlab.ui.control.Button
        LoadInfoStructfromfileButton    matlab.ui.control.Button
        SaveInfoStructButton            matlab.ui.control.Button
        AnalyzeRealTab                  matlab.ui.container.Tab
        ShiftColormapRealCheckBox       matlab.ui.control.CheckBox
        ColormapRealDropDownNewCategory  matlab.ui.control.DropDown
        SaveAllRealDropDown             matlab.ui.control.DropDown
        AngleCorrelationButton          matlab.ui.control.Button
        HistogramButton                 matlab.ui.control.Button
        ReverseColormapRealCheckBox     matlab.ui.control.CheckBox
        SquareSpinner                   matlab.ui.control.Spinner
        SquareFilterButton              matlab.ui.control.Button
        PlaneButton                     matlab.ui.control.Button
        FlattenDropDown                 matlab.ui.control.DropDown
        FlattenButton                   matlab.ui.control.Button
        SaveallRealButton               matlab.ui.control.Button
        ColormapRealDropDownNew         matlab.ui.control.DropDown
        KeepChangesNewRealButton        matlab.ui.control.Button
        GaussianFilterNewButtonReal     matlab.ui.control.Button
        RotateNewButtonReal             matlab.ui.control.Button
        VisualizeRealButton             matlab.ui.control.Button
        RealMapsButton                  matlab.ui.control.Button
        LoadContrastRealButton          matlab.ui.control.Button
        SaveContrastRealButton          matlab.ui.control.Button
        MeanIVButton                    matlab.ui.control.Button
        EditGaussianFilterReal          matlab.ui.control.NumericEditField
        EditRotateAngleReal             matlab.ui.control.NumericEditField
        MeanConductanceButton           matlab.ui.control.Button
        AnalyzeFFTTab                   matlab.ui.container.Tab
        ShiftColormapFFTCheckBox        matlab.ui.control.CheckBox
        ColormapFFTDropDownNewCategory  matlab.ui.control.DropDown
        SaveAllFFTDropDown              matlab.ui.control.DropDown
        SymmetryDropDown                matlab.ui.control.DropDown
        qanisotropyEditField            matlab.ui.control.NumericEditField
        qanisotropyButton               matlab.ui.control.Button
        ReverseColormapFFTCheckBox      matlab.ui.control.CheckBox
        SigmaCoreEditField              matlab.ui.control.NumericEditField
        SigmaEditField_2Label           matlab.ui.control.Label
        GaussiancoresubstractionButton  matlab.ui.control.Button
        SquareSpinnerFFT                matlab.ui.control.Spinner
        SquareFilterFFTButton           matlab.ui.control.Button
        SymmetrizeButton                matlab.ui.control.Button
        GaussianFilterNewButtonFFT      matlab.ui.control.Button
        RotateNewButtonFFT              matlab.ui.control.Button
        RemoveCentralLineButton         matlab.ui.control.Button
        AdjustZeroButton_2              matlab.ui.control.Button
        SaveallFFTButton                matlab.ui.control.Button
        VisualizeFFTButton              matlab.ui.control.Button
        ColormapFFTDropDownNew          matlab.ui.control.DropDown
        FFTMapsButton                   matlab.ui.control.Button
        KeepChangesNewFFTButton         matlab.ui.control.Button
        CutasviewButton                 matlab.ui.control.Button
        LoadContrastFFTButton           matlab.ui.control.Button
        SaveContrastFFTButton           matlab.ui.control.Button
        DiagonalprofileButton           matlab.ui.control.Button
        HorizontalprofileButton         matlab.ui.control.Button
        VerticalprofileButton           matlab.ui.control.Button
        RadialprofileButton             matlab.ui.control.Button
        EditProfileAngle                matlab.ui.control.NumericEditField
        AngleprofileButton              matlab.ui.control.Button
        EditGaussianFilterFFT           matlab.ui.control.NumericEditField
        EditRotateAngleFFT              matlab.ui.control.NumericEditField
        WarpCorrectionTab               matlab.ui.container.Tab
        WarpCorrectionButton            matlab.ui.control.Button
        YXRatioEditField                matlab.ui.control.NumericEditField
        YXRatioEditFieldLabel           matlab.ui.control.Label
        YShearwarpEditField             matlab.ui.control.NumericEditField
        YShearwarpEditFieldLabel        matlab.ui.control.Label
        XShearwarpEditField             matlab.ui.control.NumericEditField
        XShearwarpEditFieldLabel        matlab.ui.control.Label
        GapMappingTab                   matlab.ui.container.Tab
        GoButton                        matlab.ui.control.Button
        ContrastMaxEditField            matlab.ui.control.NumericEditField
        ContrastMaxEditFieldLabel       matlab.ui.control.Label
        ContrastMinEditField            matlab.ui.control.NumericEditField
        ContrastMinEditFieldLabel       matlab.ui.control.Label
        GaussianfilterEditField         matlab.ui.control.NumericEditField
        GaussianfilterEditFieldLabel    matlab.ui.control.Label
        CalculateGapMapButton           matlab.ui.control.Button
        ThresholdratioEditField         matlab.ui.control.NumericEditField
        ThresholdratioEditFieldLabel    matlab.ui.control.Label
        RangeMinmVEditField             matlab.ui.control.NumericEditField
        RangeMinmVEditFieldLabel        matlab.ui.control.Label
        RangePeaksmVEditField           matlab.ui.control.NumericEditField
        RangePeaksmVEditFieldLabel      matlab.ui.control.Label
        SweepdirectionLabel             matlab.ui.control.Label
        SweepDirection                  matlab.ui.container.ButtonGroup
        YButton                         matlab.ui.control.RadioButton
        XButton                         matlab.ui.control.RadioButton
        MoreFunctionsTab                matlab.ui.container.Tab
        SigmaEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel             matlab.ui.control.Label
        PopcornButton                   matlab.ui.control.Button
        AveragescatteringButton         matlab.ui.control.Button
        SymmetryTab                     matlab.ui.container.Tab
        ShiftColormapRestasCheckBox     matlab.ui.control.CheckBox
        ColormapRestasDropDownCategory  matlab.ui.control.DropDown
        ReverseColormapRestasCheckBox   matlab.ui.control.CheckBox
        ColormapRestasDropDown          matlab.ui.control.DropDown
        LoadContrastRestasButton        matlab.ui.control.Button
        SaveContrastRestasButton        matlab.ui.control.Button
        EnergySymmetryButton            matlab.ui.control.Button
    end


    properties (Access = public)
        Property % Description
        % Variables de la aplicación
        InfoStruct
        InfoStructOriginal
        InfoStructAnalisis
        InfoStructAnalisisCopy
        InfoData 
        
        fourierImage
        realImage
        
        ReverseColormapReal
        ReverseColormapFFT
        ShiftColormapReal
        ShiftColormapFFT
        
        Type
        New
        SaveType
        
        V
        H
        
        FlattenFlag
        
        % Variables coming from blqReaderApp
        blqReaderApp % App used to open blq file 
        Struct
        MatrizCorriente
        Voltaje
        %Variables coming from mapsPreviewApp
        mapsPreviewApp % App used to show generated maps
        visualizeAppReal  % App used to visualize Real space analysis
        visualizeAppFFT % App used to visualize FFT analysis
        energySymmetry % App used to visualize energy symmetry.

        colormap_list %Table containing all colormap info
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            genpath('Functions');
            addpath(genpath('Functions'));
            
            genpath('Colormap');
            addpath(genpath('Colormap'));
            
            
            colormap_listload=load ('colormap_list.mat'); 
            colormap_listload=colormap_listload.colormap_list; %matlab loades the table as an infostruct
            app.colormap_list=colormap_listload;
            app.colormap_list=sortrows(app.colormap_list,{'Prefix','Name'},{'ascend' 'ascend'});

%             genpath('Icons');
%             addpath(genpath('Icons'));         

            app.Type = 3;
            app.SaveType = 2;
            app.New = true;
            
            app.V = true;
            app.H = false;
            
            app.FlattenFlag = [1 0];
            
            app.ReverseColormapReal = false;
            app.ReverseColormapFFT = false;
            app.Type = app.SymmetryDropDown.Value;

        end

        % Button pushed function: NewAppButton
        function NewAppButtonPushed(app, event)
            app.blqReaderApp = blqReader(app);
        end

        % Button pushed function: SaveInfoStructButton
        function SaveInfoStructButtonPushed(app, event)
            saveInfo(app, app.Struct)
        end

        % Button pushed function: LoadInfoStructfromfileButton
        function LoadInfoStructfromfileButtonPushed(app, event)
            [app.InfoStruct, app.Struct] = openInfo();
            
            app.InfoStructOriginal = app.InfoStruct;
            if isfield(app.Struct,'Campo')
                app.MagneticFieldLabel.Visible = true;
                app.MagneticFieldValue.Visible = true;
                app.MagneticFieldValue.Text = [num2str(app.Struct.Campo), ' T'];
            else
                app.MagneticFieldLabel.Visible = false;
                app.MagneticFieldValue.Visible = false;
            end
            if isfield(app.Struct,'Temperatura')
                app.TemperatureLabel.Visible = true;
                app.TemperatureValue.Visible = true;
                app.TemperatureValue.Text = [num2str(app.Struct.Temperatura), ' K'];
            else
                app.TemperatureLabel.Visible = false;
                app.TemperatureValue.Visible = false;
            end
            if isfield(app.Struct,'FileName')
                app.CurrentblqLabel.Visible = true;
                app.CurrentblqName.Visible = true;
                app.CurrentblqName.Text = app.Struct.FileName;
            else
                app.CurrentblqLabel.Visible = false;
                app.CurrentblqName.Visible = false;
            end
%             if isfield(app.InfoStruct,'ColormapName')
%                 app.RealColormapLabel.Visible = true;
%                 app.RealColormapValue.Visible = true;
%                 app.RealColormapValue.Text = app.InfoStruct.ColormapName;
%                 app.FFTColormapLabel.Visible = true;
%                 app.FFTColormapValue.Visible = true;
%                 app.FFTColormapValue.Text = app.InfoStruct.ColormapName;
%             else 
%                 app.RealColormapLabel.Visible = false;
%                 app.RealColormapValue.Visible = false;
%                 app.FFTColormapLabel.Visible = false;
%                 app.FFTColormapValue.Visible = false;
%             end

            figure(app.blqAnalysisUIFigure)
            msgbox('InfoStruct succesfully loaded.','You are amazing','help')
%             app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
        end

        % Callback function
        function FFTMapsButtonPushed(app, event)
            app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
            app.InfoStructAnalisis.Colormap = eval(app.ColormapFFTDropDown.Value);
            app.InfoStructAnalisisCopy.Colormap = eval(app.ColormapFFTDropDown.Value);
            VisualizeCell(app.InfoStruct.Transformadas,app.InfoStruct)
            app.fourierImage = gcf;
            %             app.fourierImage.UserData.Colormap = app.InfoStruct.Colormap;
            app.fourierImage.Colormap = eval(app.ColormapFFTDropDown.Value);
            
            app.InfoStruct = app.fourierImage.UserData;
            app.InfoStructAnalisis = app.fourierImage.UserData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
            
            %             app.TransformadasAnalisis = app.InfoStruct.Transformadas;
        end

        % Callback function
        function KeepChangesOldButtonPushed(app, event)
            app.InfoStructAnalisis = app.realImage.UserData;
            app.InfoStructAnalisis.MapasConductancia = app.InfoStructAnalisisCopy.MapasConductancia;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Button pushed function: KeepChangesNewRealButton
        function KeepChangesNewRealButtonPushed(app, event)
            app.InfoStructAnalisis = app.InfoData;
            app.InfoStructAnalisis.MapasConductancia = app.InfoStructAnalisisCopy.MapasConductancia;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Callback function
        function RemoveCentralLineButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RemoveCentralLine(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.V, app.H);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Callback function
        function ColormapFFTDropDownValueChanged(app, event)
            value = app.ColormapFFTDropDown.Value;
            app.fourierImage.Colormap = eval(value);
            
            app.fourierImage.UserData.Colormap = eval(value);
            app.InfoStruct.Colormap = eval(value);
            app.InfoStructAnalisis.Colormap = eval(value);
            app.InfoStructAnalisisCopy.Colormap = eval(value);
        end

        % Callback function
        function RotateButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RotateMatrix(app.InfoStructAnalisis.Transformadas, app.EditRotateAngleFFT.Value, app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Callback function
        function SymmetrizeButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = simetrizarFFT_Automatico(app.InfoStructAnalisis.Transformadas, 0, app.Type,app.New);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Callback function
        function GaussianFilterButtonPushed(app, event)
            if app.EditGaussianFilterFFT.Value > 0
                app.InfoStructAnalisisCopy.Transformadas = GaussSmooth(app.InfoStructAnalisis.Transformadas, app.EditGaussianFilterFFT.Value, app.InfoStructAnalisis);                
            end
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Callback function
        function AdjustZeroButtonPushed(app, event)
            [app.InfoStructAnalisisCopy.Transformadas,  app.InfoStructAnalisisCopy] = adjustZero(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Callback function
        function SaveallFFTButtonPushed(app, event)
            saveCell(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis)
            msgbox([num2str(length(app.InfoStruct.Energia)) ' png files succesfully saved.'],'Follow your dreams','help')
        end

        % Button pushed function: MeanConductanceButton
        function MeanConductanceButtonPushed(app, event)
            meanConductance(app.InfoStruct)
        end

        % Button pushed function: VerticalprofileButton
        function VerticalprofileButtonPushed(app, event)
            verticalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: HorizontalprofileButton
        function HorizontalprofileButtonPushed(app, event)
            horizontalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: DiagonalprofileButton
        function DiagonalprofileButtonPushed(app, event)
            diagonalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            clear variables
            evalin('base','clear variables')
        end

        % Callback function
        function KeepChangesButton_2Pushed(app, event)
            for i=1:length(app.InfoStruct.Energia)
                app.fourierImage.UserData.XLim(1:2,i) = app.fourierImage.Children.XLim;
                app.fourierImage.UserData.YLim(1:2,i) = app.fourierImage.Children.YLim;
            end            
            app.InfoStructAnalisis = app.fourierImage.UserData;
            app.InfoStructAnalisis.Transformadas = app.InfoStructAnalisisCopy.Transformadas;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
            %             app.InfoStructAnalisisCopy.Contrast = app.fourierImage.UserData.Contrast;
            
            %             app.TransformadasAnalisis = app.TransformadasAnalisisCopy;
        end

        % Callback function
        function VisualizeFFTButtonPushed(app, event)
            VisualizeCell(app.InfoStructAnalisis.Transformadas,app.InfoStructAnalisisCopy)
        end

        % Menu selected function: InfoStructtoworkspaceMenu
        function InfoStructtoworkspaceMenuSelected(app, event)
            assignin('base','InfoStruct',app.InfoStruct)
            assignin('base','InfoStructOriginal',app.InfoStructOriginal)
            assignin('base','InfoStructAnalisis',app.InfoStructAnalisis)
            assignin('base','InfoStructAnalisisCopy',app.InfoStructAnalisisCopy)
            assignin('base','InfoData',app.InfoData)
            assignin('base','Struct',app.Struct)
%             assignin('base','fourierImage',app.fourierImage)
        end

        % Menu selected function: VerticalmirrorMenu
        function VerticalmirrorMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 1;
            app.VerticalHorizontalmirrorMenu.Checked = 0;
            app.C4symmetryMenu.Checked = 0;
            app.C6symmetryMenu.Checked = 0;
            app.C3symmetryMenu.Checked = 0;
            
%             app.Type = 2;
        end

        % Menu selected function: VerticalHorizontalmirrorMenu
        function VerticalHorizontalmirrorMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 0;
            app.VerticalHorizontalmirrorMenu.Checked = 1;
            app.C4symmetryMenu.Checked = 0;
            app.C6symmetryMenu.Checked = 0;
            app.C3symmetryMenu.Checked = 0;
            
%             app.Type = 1;
        end

        % Menu selected function: C4symmetryMenu
        function C4symmetryMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 0;
            app.VerticalHorizontalmirrorMenu.Checked = 0;
            app.C4symmetryMenu.Checked = 1;
            app.C6symmetryMenu.Checked = 0;
            app.C3symmetryMenu.Checked = 0;
            
%             app.Type = 3;
        end

        % Menu selected function: C6symmetryMenu
        function C6symmetryMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 0;
            app.VerticalHorizontalmirrorMenu.Checked = 0;
            app.C4symmetryMenu.Checked = 0;
            app.C6symmetryMenu.Checked = 1;
            app.C3symmetryMenu.Checked = 0;
            
%             app.Type = 4;
        end

        % Menu selected function: C3symmetryMenu
        function C3symmetryMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 0;
            app.VerticalHorizontalmirrorMenu.Checked = 0;
            app.C4symmetryMenu.Checked = 0;
            app.C6symmetryMenu.Checked = 0;
            app.C3symmetryMenu.Checked = 1;
            
%             app.Type = 5;
        end

        % Value changed function: SymmetryDropDown
        function SymmetryDropDownValueChanged(app, event)
%             app.Type = str2double(app.SymmetryDropDown.Value);
            app.Type = app.SymmetryDropDown.Value;
            %disp(app.Type)
            switch app.Type
                case {'C3','C6'}
                    msgbox( 'One Bragg peak has to align with the vertical axis!', 'Friendly reminder', 'help')
            end
%             switch app.Type
%                 case 1
%                     app.VerticalmirrorMenu.Checked = 0;
%                     app.VerticalHorizontalmirrorMenu.Checked = 1;
%                     app.C4symmetryMenu.Checked = 0;
%                     app.C6symmetryMenu.Checked = 0;
%                     app.C3symmetryMenu.Checked = 0;
%                 case 2
%                     app.VerticalmirrorMenu.Checked = 1;
%                     app.VerticalHorizontalmirrorMenu.Checked = 0;
%                     app.C4symmetryMenu.Checked = 0;
%                     app.C6symmetryMenu.Checked = 0;
%                     app.C3symmetryMenu.Checked = 0;
%                 case 3
%                     app.VerticalmirrorMenu.Checked = 0;
%                     app.VerticalHorizontalmirrorMenu.Checked = 0;
%                     app.C4symmetryMenu.Checked = 1;
%                     app.C6symmetryMenu.Checked = 0;
%                     app.C3symmetryMenu.Checked = 0;
%                 case 4
%                     app.VerticalmirrorMenu.Checked = 0;
%                     app.VerticalHorizontalmirrorMenu.Checked = 0;
%                     app.C4symmetryMenu.Checked = 0;
%                     app.C6symmetryMenu.Checked = 1;
%                     app.C3symmetryMenu.Checked = 0;
%                 case 5
%                     app.VerticalmirrorMenu.Checked = 0;
%                     app.VerticalHorizontalmirrorMenu.Checked = 0;
%                     app.C4symmetryMenu.Checked = 0;
%                     app.C6symmetryMenu.Checked = 0;
%                     app.C3symmetryMenu.Checked = 1;
%                     %disp('Careful!! One Bragg peak has to be on the vertical axis')
%                     msgbox( 'One Bragg peak has to align with the vertical axis!', 'Friendly reminder', 'help')
%             end
        end

        % Callback function
        function RealMapsOldButtonPushed(app, event)
            app.InfoStruct.Colormap = eval(app.ColormapRealDropDown.Value);
            app.InfoStructAnalisis.Colormap = eval(app.ColormapRealDropDown.Value);
            app.InfoStructAnalisisCopy.Colormap = eval(app.ColormapRealDropDown.Value);
            VisualizeCellReal(app.InfoStruct.MapasConductancia,app.InfoStruct)
            app.realImage = gcf;
            %             app.fourierImage.UserData.Colormap = app.InfoStruct.Colormap;
            app.realImage.Colormap = eval(app.ColormapRealDropDown.Value);
            
            app.InfoStruct = app.realImage.UserData;
            app.InfoStructAnalisis = app.realImage.UserData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Button pushed function: RealMapsButton
        function RealMapsButtonPushed(app, event)
            app.InfoStruct.XLimReal = [min(app.InfoStruct.DistanciaColumnas) max(app.InfoStruct.DistanciaColumnas)];
            app.InfoStruct.YLimReal = [min(app.InfoStruct.DistanciaFilas) max(app.InfoStruct.DistanciaFilas)];
            
            app.visualizeAppReal = analyze(app);
            app.visualizeAppReal.UIFigure.Name = 'Analyze Real Maps';
%             app.visualizeAppFFT.AnalyzeRealLabel.Text = 'Analyze FFT';
            app.visualizeAppReal.Cell = app.InfoStruct.MapasConductancia;
            app.visualizeAppReal.isReal = true;
            app.visualizeAppReal.Axes.Tag = 'RealAxes';
            app.visualizeAppReal.Axes.Colormap = app.InfoStruct.Colormap;
            initCell(app.visualizeAppReal, app.InfoStruct.MapasConductancia, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStruct.MapasConductancia, app.InfoStruct, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
            
%             Ratio = (app.visualizeAppReal.Axes.XLim(2) - app.visualizeAppReal.Axes.XLim(1))/(app.visualizeAppReal.Axes.YLim(2) - app.visualizeAppReal.Axes.YLim(1));
%             app.visualizeAppReal.Axes.DataAspectRatio = [1,Ratio,1];
%             app.visualizeAppReal.Axes.PlotBoxAspectRatio = [1,Ratio,1];
            
            app.InfoStruct = app.InfoData;
            app.InfoStructAnalisis = app.InfoData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Callback function
        function ColormapRealDropDownValueChanged(app, event)
            value = app.ColormapRealDropDown.Value;
            app.realImage.Colormap = eval(value);
            
            app.realImage.UserData.Colormap = eval(value);
            app.InfoStruct.Colormap = eval(value);
            app.InfoStructAnalisis.Colormap = eval(value);
            app.InfoStructAnalisisCopy.Colormap = eval(value);
        end

        % Value changed function: ColormapRealDropDownNew
        function ColormapRealDropDownNewValueChanged(app, event)
            value = app.ColormapRealDropDownNew.Value;

            switch app.colormap_list.Prefix{strcmp(app.colormap_list.Name,value)}
                case 'CET'
                    colorout=colorcet(value);
                case 'sci'
                    colorout=crameri(value);
                case 'oce'
                    colorout=cmocean(value);
                otherwise
                    colorout=eval(value);
            end
            % Obtain shifted colormap matrix for cyclic colormaps
            if (app.ShiftColormapReal & app.ShiftColormapRealCheckBox.Enable)
                colorout = circshift(colorout,size(colorout,1)/2);
            end
            % Obtain flipped colormap matrix or the usual one
            if app.ReverseColormapReal
                colorout = flipud(colorout);
            end

%             %Turn the labels visible
%             app.RealColormapLabel.Visible = true;
%             app.RealColormapValue.Visible = true;
%             app.RealColormapValue.Text = value;
%             app.FFTColormapLabel.Visible = true;
%             app.FFTColormapValue.Visible = true;
%             app.FFTColormapValue.Text = value;

            % Assign the colormap to all these fields.
            [app.visualizeAppReal.Axes.Colormap,...
                app.InfoData.Colormap,...
                app.InfoStruct.Colormap,...
                app.InfoStructAnalisis.Colormap,...
                app.InfoStructAnalisisCopy.Colormap] ...
                = deal(colorout);
            [app.visualizeAppReal.ColormapValue.Text,...
                app.InfoData.ColormapName,...
                app.InfoStruct.ColormapName,...
                app.InfoStructAnalisis.ColormapName,...
                app.InfoStructAnalisisCopy.ColormapName] = deal(value);
            app.visualizeAppReal.ColormapLabel.Visible = true;
            app.visualizeAppReal.ColormapValue.Visible = true;
        end

        % Callback function
        function VisualizeRealOldButtonPushed(app, event)
            VisualizeCellReal(app.InfoStructAnalisis.MapasConductancia,app.InfoStructAnalisisCopy)
        end

        % Button pushed function: VisualizeRealButton
        function VisualizeRealButtonPushed(app, event)
            app.visualizeAppReal.Cell = app.InfoStructAnalisis.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisis.MapasConductancia, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStruct.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Callback function
        function RotateOldButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = RotateMatrix(app.InfoStructAnalisis.MapasConductancia, app.EditRotateAngleReal.Value, app.InfoStructAnalisis);
            VisualizeCellReal(app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy);
        end

        % Callback function
        function GaussianFilterOldButtonPushed(app, event)
            if app.EditGaussianFilterReal.Value > 0
                app.InfoStructAnalisisCopy.MapasConductancia = GaussSmooth(app.InfoStructAnalisis.MapasConductancia, app.EditGaussianFilterReal.Value, app.InfoStructAnalisis);                
            end
            VisualizeCellReal(app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: MeanIVButton
        function MeanIVButtonPushed(app, event)
            meanIV(app.InfoStruct)
        end

        % Callback function
        function SaveallRealOldButtonPushed(app, event)
            saveCell(app.InfoStructAnalisis.MapasConductancia, app.InfoStructAnalisis)
            msgbox([num2str(length(app.InfoStruct.Energia)) ' png files succesfully saved.'],'Follow your dreams','help')
        end

        % Button pushed function: SaveContrastFFTButton
        function SaveContrastFFTButtonPushed(app, event)
            assignin('base','ContrastFFT',app.InfoStructAnalisis.ContrastFFT)
        end

        % Button pushed function: LoadContrastFFTButton
        function LoadContrastFFTButtonPushed(app, event)
            Contrast = evalin('base','ContrastFFT');
            app.fourierImage.UserData.ContrastFFT = Contrast;
            app.InfoStruct.ContrastFFT = Contrast;
            app.InfoStructAnalisis.ContrastFFT = Contrast;
            app.InfoStructAnalisisCopy.ContrastFFT = Contrast;
        end

        % Button pushed function: SaveContrastRealButton
        function SaveContrastRealButtonPushed(app, event)
            assignin('base','ContrastReal',app.InfoStructAnalisis.ContrastReal)
        end

        % Button pushed function: LoadContrastRealButton
        function LoadContrastRealButtonPushed(app, event)
            Contrast = evalin('base','ContrastReal');
            app.realImage.UserData.ContrastReal = Contrast;
            app.InfoStruct.ContrastReal = Contrast;
            app.InfoStructAnalisis.ContrastReal = Contrast;
            app.InfoStructAnalisisCopy.ContrastReal = Contrast;
        end

        % Menu selected function: RecalculateFFTMenu
        function RecalculateFFTMenuSelected(app, event)
            app.InfoStructAnalisis = rmfield(app.InfoStructAnalisis,'Transformadas');
            app.InfoStructAnalisisCopy = rmfield(app.InfoStructAnalisisCopy,'Transformadas');
%             for i=1:length(app.InfoStructAnalisis.Energia)
%                 app.InfoStruct.Transformadas{i} = fft2d(app.InfoStructAnalisis.MapasConductancia{i});
%             end
            %Deberia ser un poco mas rapido al calcular
            app.InfoStruct.Transformadas = cellfun(@fft2d,app.InfoStructAnalisis.MapasConductancia, 'UniformOutput', false);
            msgbox('FFT maps recalculated.',"Don't give up",'help')
        end

        % Button pushed function: AveragescatteringButton
        function AveragescatteringButtonPushed(app, event)
            averageScattering(app.InfoStructAnalisis)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            app.NewMenu.Checked = 1;
            app.OldMenu.Checked = 0;
                        
            app.New = true;
        end

        % Menu selected function: OldMenu
        function OldMenuSelected(app, event)
            app.NewMenu.Checked = 0;
            app.OldMenu.Checked = 1;
                        
            app.New = false;
        end

        % Menu selected function: VerticalMenu
        function VerticalMenuSelected(app, event)
            app.VerticalMenu.Checked = 'on';
            app.HorizontalMenu.Checked = 'off';
            app.VerticalHorizontalMenu.Checked = 'off';
            
            app.V = true;
            app.H = false;
        end

        % Menu selected function: HorizontalMenu
        function HorizontalMenuSelected(app, event)
            app.VerticalMenu.Checked = 'off';
            app.HorizontalMenu.Checked = 'on';
            app.VerticalHorizontalMenu.Checked = 'off';
            
            app.V = false;
            app.H = true;
        end

        % Menu selected function: VerticalHorizontalMenu
        function VerticalHorizontalMenuSelected(app, event)
            app.VerticalMenu.Checked = 'off';
            app.HorizontalMenu.Checked = 'off';
            app.VerticalHorizontalMenu.Checked = 'on';
            
            app.V = true;
            app.H = true;
        end

        % Button pushed function: CutasviewButton
        function CutasviewButtonPushed(app, event)
            app.InfoStructAnalisisCopy = cutMatrix(app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Close request function: blqAnalysisUIFigure
        function blqAnalysisUIFigureCloseRequest(app, event)
            delete(app.mapsPreviewApp)
            delete(app.blqReaderApp)
            delete(app)
        end

        % Button pushed function: LoadInfoStructfromworkspaceButton
        function LoadInfoStructfromworkspaceButtonPushed(app, event)
            app.InfoStruct = readInfo();
            app.InfoStructOriginal = app.InfoStruct;
            figure(app.blqAnalysisUIFigure)
            msgbox('InfoStruct succesfully loaded.','You are amazing','help')
        end

        % Button pushed function: CutInfoStructButton
        function CutInfoStructButtonPushed(app, event)
            x1 = app.x1EditField.Value;
            x2 = app.x2EditField.Value;
            y1 = app.y1EditField.Value;
            y2 = app.y2EditField.Value;
            
            app.InfoStruct = RecorteQPI(x1,x2,y1,y2,app.InfoStructOriginal);
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            app.InfoStruct = app.InfoStructOriginal;
        end

        % Button pushed function: RotateNewButtonReal
        function RotateNewButtonRealPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = RotateMatrix(app.InfoStructAnalisis.MapasConductancia, app.EditRotateAngleReal.Value, app.InfoStructAnalisis);
            
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: KeepChangesNewFFTButton
        function KeepChangesNewFFTButtonPushed(app, event)
            app.InfoStructAnalisis = app.InfoData;
            app.InfoStructAnalisis.Transformadas = app.InfoStructAnalisisCopy.Transformadas;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Button pushed function: GaussianFilterNewButtonReal
        function GaussianFilterNewButtonRealPushed(app, event)
            if app.EditGaussianFilterReal.Value > 0
                app.InfoStructAnalisisCopy.MapasConductancia = GaussSmooth(app.InfoStructAnalisis.MapasConductancia, app.EditGaussianFilterReal.Value, app.InfoStructAnalisis);                
            end
            
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: FFTMapsButton
        function FFTMapsButtonPushed2(app, event)
            app.InfoStruct.XLimFFT = [min(app.InfoStruct.DistanciaFourierColumnas) max(app.InfoStruct.DistanciaFourierColumnas)];
            app.InfoStruct.YLimFFT = [min(app.InfoStruct.DistanciaFourierFilas) max(app.InfoStruct.DistanciaFourierFilas)];
            
            app.visualizeAppFFT = analyze(app);
            app.visualizeAppFFT.UIFigure.Name = 'Analyze FFT Maps';
            app.visualizeAppFFT.AnalyzeRealLabel.Text = 'Analyze FFT';
            app.visualizeAppFFT.Cell = app.InfoStruct.Transformadas;
            app.visualizeAppFFT.isReal = false;
            app.visualizeAppFFT.Axes.Tag = 'FFTAxes';
            app.visualizeAppFFT.Axes.Colormap = app.InfoStruct.Colormap;
            initCell(app.visualizeAppFFT, app.InfoStruct.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStruct.Transformadas, app.InfoStruct, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
            
%             Ratio = (app.visualizeAppFFT.Axes.XLim(2) - app.visualizeAppFFT.Axes.XLim(1))/(app.visualizeAppFFT.Axes.YLim(2) - app.visualizeAppFFT.Axes.YLim(1));
%             app.visualizeAppFFT.Axes.DataAspectRatio = [1,Ratio,1];
%                 
            app.InfoStruct = app.InfoData;
            app.InfoStructAnalisis = app.InfoData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Value changed function: ColormapFFTDropDownNew
        function ColormapFFTDropDownNewValueChanged(app, event)
            value = app.ColormapFFTDropDownNew.Value;
            switch app.colormap_list.Prefix{strcmp(app.colormap_list.Name,value)}
                case 'CET'
                    colorout=colorcet(value);
                case 'sci'
                    colorout=crameri(value);
                case 'oce'
                    colorout=cmocean(value);
                otherwise
                    colorout=eval(value);
            end
            % Obtain shifted colormap matrix for cyclic colormaps
            if (app.ShiftColormapFFT & app.ShiftColormapFFTCheckBox.Enable)
                colorout = circshift(colorout,size(colorout,1)/2);
            end
            % Obtain flipped colormap matrix or the usual one
            if app.ReverseColormapFFT
                colorout = flipud(colorout);
            end

%             %Turn the labels visible
%             app.RealColormapLabel.Visible = true;
%             app.RealColormapValue.Visible = true;
%             app.RealColormapValue.Text = value;
%             app.FFTColormapLabel.Visible = true;
%             app.FFTColormapValue.Visible = true;
%             app.FFTColormapValue.Text = value;

            % Assign the colormap to all these fields.
            [app.visualizeAppFFT.Axes.Colormap,...
                app.InfoData.Colormap,...
                app.InfoStruct.Colormap,...
                app.InfoStructAnalisis.Colormap,...
                app.InfoStructAnalisisCopy.Colormap] ...
                = deal(colorout);
            [app.visualizeAppFFT.ColormapValue.Text,...
                app.InfoData.ColormapName,...
                app.InfoStruct.ColormapName,...
                app.InfoStructAnalisis.ColormapName,...
                app.InfoStructAnalisisCopy.ColormapName] = deal(value);
            app.visualizeAppFFT.ColormapValue.Visible = true;
            app.visualizeAppFFT.ColormapLabel.Visible = true;
        end

        % Button pushed function: VisualizeFFTButton
        function VisualizeFFTButtonPushed2(app, event)
            app.visualizeAppFFT.Cell = app.InfoStructAnalisis.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisis.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: RotateNewButtonFFT
        function RotateNewButtonFFTPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RotateMatrix(app.InfoStructAnalisis.Transformadas, app.EditRotateAngleFFT.Value, app.InfoStructAnalisis);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: RemoveCentralLineButton
        function RemoveCentralLineButtonPushed2(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RemoveCentralLine(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.V, app.H);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: SymmetrizeButton
        function SymmetrizeButtonPushed2(app, event)
            disp(app.Type)
            app.InfoStructAnalisisCopy.Transformadas = simetrizarFFT_Automatico(app.InfoStructAnalisis.Transformadas, 0, app.Type,app.New);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: GaussianFilterNewButtonFFT
        function GaussianFilterNewButtonFFTPushed(app, event)
            if app.EditGaussianFilterFFT.Value > 0
                app.InfoStructAnalisisCopy.Transformadas = GaussSmooth(app.InfoStructAnalisis.Transformadas, app.EditGaussianFilterFFT.Value, app.InfoStructAnalisis);                
            end
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: AdjustZeroButton_2
        function AdjustZeroButton_2Pushed(app, event)
            [app.InfoStructAnalisisCopy.Transformadas,  app.InfoStructAnalisisCopy] = adjustZero(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: SaveallFFTButton
        function SaveallFFTButtonPushed2(app, event)
            saveCell_v2(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.visualizeAppFFT.isReal, app.SaveType)
            msgbox([num2str(length(app.InfoStruct.Energia)) ' png files succesfully saved.'],'Follow your dreams','help')
        end

        % Menu selected function: NotitlenoaxesMenu
        function NotitlenoaxesMenuSelected(app, event)
            app.NotitlenoaxesMenu.Checked = true;
            app.OnlytitleMenu.Checked = false;
            app.TitleandaxesMenu.Checked = false;
            
            app.SaveType = 0;
        end

        % Menu selected function: OnlytitleMenu
        function OnlytitleMenuSelected(app, event)
            app.NotitlenoaxesMenu.Checked = false;
            app.OnlytitleMenu.Checked = true;
            app.TitleandaxesMenu.Checked = false;
            
            app.SaveType = 1;
        end

        % Menu selected function: TitleandaxesMenu
        function TitleandaxesMenuSelected(app, event)
            app.NotitlenoaxesMenu.Checked = false;
            app.OnlytitleMenu.Checked = false;
            app.TitleandaxesMenu.Checked = true;
            
            app.SaveType = 2;
        end

        % Button pushed function: SaveallRealButton
        function SaveallRealButtonPushed(app, event)
            saveCell_v2(app.InfoStructAnalisis.MapasConductancia, app.InfoStructAnalisis, app.visualizeAppReal.isReal, app.SaveType)
            msgbox([num2str(length(app.InfoStruct.Energia)) ' png files succesfully saved.'],'Follow your dreams','help')
        end

        % Value changed function: FlattenDropDown
        function FlattenDropDownValueChanged(app, event)
            value = app.FlattenDropDown.Value;
            
            if strcmp(value,'Horizontal')
                app.FlattenFlag = [1 0];
            elseif strcmp(value,'Vertical')
                app.FlattenFlag = [0 1];
            elseif strcmp(value,'Both')
                app.FlattenFlag = [1 1];
            end
        end

        % Button pushed function: FlattenButton
        function FlattenButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = FlattenCell(app.InfoStructAnalisis.MapasConductancia, app.FlattenFlag, app.InfoStructAnalisis);
            
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, true)
            app.visualizeAppReal.k
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: CalculateGapMapButton
        function CalculateGapMapButtonPushed(app, event)
            app.InfoStruct = gapMap(app.InfoStructAnalisis,app.RangePeaksmVEditField.Value,app.ThresholdratioEditField.Value,app.RangeMinmVEditField.Value);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if app.GaussianfilterEditField.Value>0
                MapaGap = imgaussfilt(app.InfoStruct.MapaGap,app.GaussianfilterEditField.Value);
            else
                MapaGap = app.InfoStruct.MapaGap;
            end
            
            fig = figure;
            imagesc(app.InfoStruct.DistanciaColumnas,app.InfoStruct.DistanciaFilas,MapaGap);
            fig.Children.YDir = 'normal';
            fig.Children.CLim = [app.ContrastMinEditField.Value app.ContrastMaxEditField.Value];
            colormap jet
            axis square
            
            assignin("base",'MapaGap', MapaGap)
        end

        % Callback function
        function ShearWarpButtonPushed(app, event)
            
        end

        % Button pushed function: WarpCorrectionButton
        function WarpCorrectionButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = WarpMatrix(app.InfoStructAnalisis.Transformadas, app.XShearwarpEditField.Value, app.YShearwarpEditField.Value, app.YXRatioEditField.Value, app.InfoStructAnalisis);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, false)
            
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);

        end

        % Button pushed function: PopcornButton
        function PopcornButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = popcorn(app.InfoStructAnalisis.MapasConductancia, app.SigmaEditField.Value, app.InfoStructAnalisis);
           
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, true)
            app.visualizeAppReal.k
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: GeneratemapsButton
        function GeneratemapsButtonPushed(app, event)
            %Solo funciona si acabamos de generar el struct, pero no si lo
            %volvemos a cargar desde un archivo previo
            if isfield(app.Struct,'SaveFolder')
                [app.Struct] = analyzeImages(app.Struct);
                maps = num2str(length(app.CallingApp.Struct.Energia));
                uiwait(msgbox([maps ' maps succesfully generated'], 'You are almost there', 'help'))
                app.CallingApp.mapsPreviewApp = mapsPreview(app.CallingApp, app.CallingApp.Struct);
            else
                uiwait(msgbox('Maps cannot be generated from a previously saved file','This is going nowhere','warn'))
            end
        end

        % Button pushed function: PlaneButton
        function PlaneButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = planeCell(app.InfoStructAnalisis.MapasConductancia, app.InfoStructAnalisis);
            
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, true)
            app.visualizeAppReal.k
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: SquareFilterButton
        function SquareFilterButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = squareFilter(app.InfoStructAnalisis.MapasConductancia, app.InfoStructAnalisis, app.SquareSpinner.Value);
            
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, true)
            app.visualizeAppReal.k
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: SquareFilterFFTButton
        function SquareFilterFFTButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = squareFilter(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.SquareSpinnerFFT.Value);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: GaussiancoresubstractionButton
        function GaussiancoresubstractionButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = gaussCoreSubstraction(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.SigmaCoreEditField.Value);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Value changed function: ReverseColormapFFTCheckBox
        function ReverseColormapFFTCheckBoxValueChanged(app, event)
            value = app.ReverseColormapFFTCheckBox.Value;
            
            app.ReverseColormapFFT = value;
            app.ColormapFFTDropDownNewValueChanged;
        end

        % Value changed function: ReverseColormapRealCheckBox
        function ReverseColormapRealCheckBoxValueChanged(app, event)
            value = app.ReverseColormapRealCheckBox.Value;
            
            app.ReverseColormapReal = value;
            app.ColormapRealDropDownNewValueChanged;
        end

        % Button pushed function: qanisotropyButton
        function qanisotropyButtonPushed(app, event)
            qanisotropy(app.InfoStructAnalisis,app.qanisotropyEditField.Value)  
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            histogramMap(app.InfoStructAnalisis,app.visualizeAppReal.k)
        end

        % Button pushed function: AngleCorrelationButton
        function AngleCorrelationButtonPushed(app, event)
            angleCorrelation(app.InfoStructAnalisis,app.visualizeAppReal.k)
        end

        % Value changed function: SaveAllFFTDropDown
        function SaveAllFFTDropDownValueChanged(app, event)
            app.SaveType = str2double (app.SaveAllFFTDropDown.Value);
            app.SaveAllRealDropDown.Value = app.SaveAllFFTDropDown.Value;            
        end

        % Value changed function: SaveAllRealDropDown
        function SaveAllRealDropDownValueChanged(app, event)
            app.SaveType = str2double (app.SaveAllRealDropDown.Value);
            app.SaveAllFFTDropDown.Value = app.SaveAllRealDropDown.Value;
        end

        % Value changed function: ColormapRealDropDownNewCategory
        function ColormapRealDropDownNewCategoryValueChanged(app, event)
            value = app.ColormapRealDropDownNewCategory.Value;
            switch value
                case 'Most Used'
                    app.ColormapRealDropDownNew.Items={'parula','viridis','plasma','magma','inferno','bone','gray'};
                    app.ColormapRealDropDownNew.ItemsData={'parula','viridis','plasma','magma','inferno','bone','gray'};
                case 'Sequential'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Sequential);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Sequential);
                case 'Diverging'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Diverging);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Diverging);
                case 'Cyclic'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Cyclic);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Cyclic);
                case 'Misc'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Misc);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Misc);
                case 'Categorical'
                    msgbox('This maps are meant to be used for discrete values of data','Use carefully','warn')
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Categorical);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Categorical);
                case 'Uniform Sequential'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Sequential);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Sequential);
                case 'Uniform Diverging'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Diverging);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Diverging);
                case 'Uniform Cyclic'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Cyclic);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Cyclic);
            end
            switch app.ColormapRealDropDownNewCategory.Value
                case {'Cyclic','Uniform Cyclic'}
                    app.ShiftColormapRealCheckBox.Visible = true;
                    app.ShiftColormapRealCheckBox.Enable = true;
                otherwise
                    app.ShiftColormapRealCheckBox.Visible = false;
                    app.ShiftColormapRealCheckBox.Enable = false;
                    % app.ShiftColormapReal = False;
            end
            app.ColormapRealDropDownNewValueChanged;
        end

        % Value changed function: ShiftColormapRealCheckBox
        function ShiftColormapRealCheckBoxValueChanged(app, event)
            value = app.ShiftColormapRealCheckBox.Value;
            
            app.ShiftColormapReal = value;
            app.ColormapRealDropDownNewValueChanged;
        end

        % Value changed function: ShiftColormapFFTCheckBox
        function ShiftColormapFFTCheckBoxValueChanged(app, event)
            value = app.ShiftColormapFFTCheckBox.Value;
            
            app.ShiftColormapFFT = value;
            app.ColormapFFTDropDownNewValueChanged;
        end

        % Value changed function: ColormapFFTDropDownNewCategory
        function ColormapFFTDropDownNewCategoryValueChanged(app, event)
            value = app.ColormapFFTDropDownNewCategory.Value;
             switch value 
                case 'Most Used'
                    app.ColormapFFTDropDownNew.Items={'parula','viridis','plasma','magma','inferno','bone','gray'};
                    app.ColormapFFTDropDownNew.ItemsData={'parula','viridis','plasma','magma','inferno','bone','gray'};
                case 'Sequential'
                    app.ColormapFFTDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Sequential);
                    app.ColormapFFTDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Sequential);
                case 'Diverging'
                    app.ColormapFFTDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Diverging);
                    app.ColormapFFTDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Diverging);
                case 'Cyclic'
                    app.ColormapFFTDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Cyclic);
                    app.ColormapFFTDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Cyclic);
                case 'Misc'
                    app.ColormapFFTDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Misc);
                    app.ColormapFFTDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Misc);
                case 'Categorical'
                    msgbox('This maps are meant to be used for discrete values of data','Use carefully','warn')
                    app.ColormapFFTDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Categorical);
                    app.ColormapFFTDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Categorical);
                case 'Uniform Sequential'
                    app.ColormapFFTDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Sequential);
                    app.ColormapFFTDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Sequential);
                case 'Uniform Diverging'
                    app.ColormapFFTDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Diverging);
                    app.ColormapFFTDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Diverging);
                case 'Uniform Cyclic'
                    app.ColormapFFTDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Cyclic);
                    app.ColormapFFTDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Cyclic);
            end 
            switch app.ColormapFFTDropDownNewCategory.Value
                case {'Cyclic','Uniform Cyclic'}
                    app.ShiftColormapFFTCheckBox.Visible = true;
                    app.ShiftColormapFFTCheckBox.Enable = true;
                otherwise
                    app.ShiftColormapFFTCheckBox.Visible = false;
                    app.ShiftColormapFFTCheckBox.Enable = false;
                    % app.ShiftColormapFFT = False;
            end

            app.ColormapFFTDropDownNewValueChanged;
        end

        % Button pushed function: EnergySymmetryButton
        function EnergySymmetryButtonPushed(app, event)
            app.energySymmetry = EnergySym(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.blqAnalysisUIFigure = uifigure('Visible', 'off');
            app.blqAnalysisUIFigure.Name = 'blqAnalysis';
            app.blqAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @blqAnalysisUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.blqAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.InfoStructtoworkspaceMenu = uimenu(app.FileMenu);
            app.InfoStructtoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoStructtoworkspaceMenuSelected, true);
            app.InfoStructtoworkspaceMenu.Text = 'InfoStruct to workspace';
            app.EditMenu = uimenu(app.blqAnalysisUIFigure);
            app.EditMenu.Text = 'Edit';
            app.RecalculateFFTMenu = uimenu(app.EditMenu);
            app.RecalculateFFTMenu.MenuSelectedFcn = createCallbackFcn(app, @RecalculateFFTMenuSelected, true);
            app.RecalculateFFTMenu.Text = 'Recalculate FFT';
            app.RemoveCentralLineMenu = uimenu(app.EditMenu);
            app.RemoveCentralLineMenu.Text = 'Remove Central Line';
            app.VerticalMenu = uimenu(app.RemoveCentralLineMenu);
            app.VerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalMenuSelected, true);
            app.VerticalMenu.Checked = 'on';
            app.VerticalMenu.Text = 'Vertical';
            app.HorizontalMenu = uimenu(app.RemoveCentralLineMenu);
            app.HorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @HorizontalMenuSelected, true);
            app.HorizontalMenu.Text = 'Horizontal';
            app.VerticalHorizontalMenu = uimenu(app.RemoveCentralLineMenu);
            app.VerticalHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalHorizontalMenuSelected, true);
            app.VerticalHorizontalMenu.Text = 'Vertical + Horizontal';
            app.SymmetrizeMenu = uimenu(app.blqAnalysisUIFigure);
            app.SymmetrizeMenu.Visible = 'off';
            app.SymmetrizeMenu.Enable = 'off';
            app.SymmetrizeMenu.Text = 'Symmetrize';
            app.VerticalmirrorMenu = uimenu(app.SymmetrizeMenu);
            app.VerticalmirrorMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalmirrorMenuSelected, true);
            app.VerticalmirrorMenu.Text = 'Vertical mirror';
            app.VerticalHorizontalmirrorMenu = uimenu(app.SymmetrizeMenu);
            app.VerticalHorizontalmirrorMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalHorizontalmirrorMenuSelected, true);
            app.VerticalHorizontalmirrorMenu.Text = 'Vertical + Horizontal mirror';
            app.C4symmetryMenu = uimenu(app.SymmetrizeMenu);
            app.C4symmetryMenu.MenuSelectedFcn = createCallbackFcn(app, @C4symmetryMenuSelected, true);
            app.C4symmetryMenu.Checked = 'on';
            app.C4symmetryMenu.Text = 'C4 symmetry';
            app.C6symmetryMenu = uimenu(app.SymmetrizeMenu);
            app.C6symmetryMenu.MenuSelectedFcn = createCallbackFcn(app, @C6symmetryMenuSelected, true);
            app.C6symmetryMenu.Text = 'C6 symmetry';
            app.C3symmetryMenu = uimenu(app.SymmetrizeMenu);
            app.C3symmetryMenu.MenuSelectedFcn = createCallbackFcn(app, @C3symmetryMenuSelected, true);
            app.C3symmetryMenu.Text = 'C3 symmetry';
            app.CellMenu = uimenu(app.blqAnalysisUIFigure);
            app.CellMenu.Text = 'Cell';
            app.NewMenu = uimenu(app.CellMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Checked = 'on';
            app.NewMenu.Text = 'New';
            app.OldMenu = uimenu(app.CellMenu);
            app.OldMenu.MenuSelectedFcn = createCallbackFcn(app, @OldMenuSelected, true);
            app.OldMenu.Text = 'Old';
            app.SaveallMenu = uimenu(app.blqAnalysisUIFigure);
            app.SaveallMenu.Visible = 'off';
            app.SaveallMenu.Enable = 'off';
            app.SaveallMenu.Text = 'Save all';
            app.NotitlenoaxesMenu = uimenu(app.SaveallMenu);
            app.NotitlenoaxesMenu.MenuSelectedFcn = createCallbackFcn(app, @NotitlenoaxesMenuSelected, true);
            app.NotitlenoaxesMenu.Text = 'No title, no axes';
            app.OnlytitleMenu = uimenu(app.SaveallMenu);
            app.OnlytitleMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlytitleMenuSelected, true);
            app.OnlytitleMenu.Text = 'Only title';
            app.TitleandaxesMenu = uimenu(app.SaveallMenu);
            app.TitleandaxesMenu.MenuSelectedFcn = createCallbackFcn(app, @TitleandaxesMenuSelected, true);
            app.TitleandaxesMenu.Checked = 'on';
            app.TitleandaxesMenu.Text = 'Title and axes';
            app.SaveformatMenu = uimenu(app.blqAnalysisUIFigure);
            app.SaveformatMenu.Visible = 'off';
            app.SaveformatMenu.Enable = 'off';
            app.SaveformatMenu.Text = 'Save format';
            app.jpegMenu = uimenu(app.SaveformatMenu);
            app.jpegMenu.Text = 'jpeg';
            app.pngMenu = uimenu(app.SaveformatMenu);
            app.pngMenu.Checked = 'on';
            app.pngMenu.Text = 'png';
            app.svgMenu = uimenu(app.SaveformatMenu);
            app.svgMenu.Text = 'svg';
            app.TabGroup = uitabgroup(app.blqAnalysisUIFigure);
            app.OpenTab = uitab(app.TabGroup);
            app.OpenTab.Title = 'Open';
            app.SaveInfoStructButton = uibutton(app.OpenTab, 'push');
            app.SaveInfoStructButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInfoStructButtonPushed, true);
            app.SaveInfoStructButton.Text = 'Save InfoStruct';
            app.LoadInfoStructfromfileButton = uibutton(app.OpenTab, 'push');
            app.LoadInfoStructfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfoStructfromfileButtonPushed, true);
            app.LoadInfoStructfromfileButton.Text = 'Load InfoStruct from file';
            app.ClearallButton = uibutton(app.OpenTab, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.NewAppButton = uibutton(app.OpenTab, 'push');
            app.NewAppButton.ButtonPushedFcn = createCallbackFcn(app, @NewAppButtonPushed, true);
            app.NewAppButton.Text = 'Open blqReader';
            app.LoadInfoStructfromworkspaceButton = uibutton(app.OpenTab, 'push');
            app.LoadInfoStructfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfoStructfromworkspaceButtonPushed, true);
            app.LoadInfoStructfromworkspaceButton.Text = 'Load InfoStruct from workspace';
            app.CutInfoStructButton = uibutton(app.OpenTab, 'push');
            app.CutInfoStructButton.ButtonPushedFcn = createCallbackFcn(app, @CutInfoStructButtonPushed, true);
            app.CutInfoStructButton.Text = 'Cut InfoStruct';
            app.x1EditFieldLabel = uilabel(app.OpenTab);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.OpenTab, 'numeric');
            app.x1EditField.Value = 1;
            app.x2EditFieldLabel = uilabel(app.OpenTab);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.OpenTab, 'numeric');
            app.x2EditField.Value = 128;
            app.y1EditFieldLabel = uilabel(app.OpenTab);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.OpenTab, 'numeric');
            app.y1EditField.Value = 1;
            app.y2EditFieldLabel = uilabel(app.OpenTab);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.OpenTab, 'numeric');
            app.y2EditField.Value = 128;
            app.RestoreButton = uibutton(app.OpenTab, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.SinglecurvesButton = uibutton(app.OpenTab, 'push');
            app.SinglecurvesButton.Text = 'Single curves';
            app.GeneratemapsButton = uibutton(app.OpenTab, 'push');
            app.GeneratemapsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemapsButtonPushed, true);
            app.GeneratemapsButton.Text = 'Generate maps';
            app.CurrentInfoStructLabel = uilabel(app.OpenTab);
            app.CurrentInfoStructLabel.Enable = 'off';
            app.CurrentInfoStructLabel.Visible = 'off';
            app.CurrentInfoStructLabel.Text = 'Current InfoStruct :';
            app.CurrentblqLabel = uilabel(app.OpenTab);
            app.CurrentblqLabel.Visible = 'off';
            app.CurrentblqLabel.Text = 'Current blq :';
            app.CurrentInfoStructName = uilabel(app.OpenTab);
            app.CurrentInfoStructName.Enable = 'off';
            app.CurrentInfoStructName.Visible = 'off';
            app.CurrentInfoStructName.Text = '';
            app.CurrentblqName = uilabel(app.OpenTab);
            app.CurrentblqName.Text = '';
            app.AnalyzeRealTab = uitab(app.TabGroup);
            app.AnalyzeRealTab.Title = 'Analyze Real';
            app.MeanConductanceButton = uibutton(app.AnalyzeRealTab, 'push');
            app.MeanConductanceButton.ButtonPushedFcn = createCallbackFcn(app, @MeanConductanceButtonPushed, true);
            app.MeanConductanceButton.Text = 'Mean Conductance';
            app.EditRotateAngleReal = uieditfield(app.AnalyzeRealTab, 'numeric');
            app.EditGaussianFilterReal = uieditfield(app.AnalyzeRealTab, 'numeric');
            app.MeanIVButton = uibutton(app.AnalyzeRealTab, 'push');
            app.MeanIVButton.ButtonPushedFcn = createCallbackFcn(app, @MeanIVButtonPushed, true);
            app.MeanIVButton.Text = 'Mean IV';
            app.SaveContrastRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.SaveContrastRealButton.ButtonPushedFcn = createCallbackFcn(app, @SaveContrastRealButtonPushed, true);
            app.SaveContrastRealButton.Text = 'Save Contrast';
            app.LoadContrastRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.LoadContrastRealButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContrastRealButtonPushed, true);
            app.LoadContrastRealButton.Text = 'Load Contrast';
            app.RealMapsButton = uibutton(app.AnalyzeRealTab, 'push');
            app.RealMapsButton.ButtonPushedFcn = createCallbackFcn(app, @RealMapsButtonPushed, true);
            app.RealMapsButton.Text = 'Real Maps';
            app.VisualizeRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.VisualizeRealButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeRealButtonPushed, true);
            app.VisualizeRealButton.Text = 'Visualize Real';
            app.RotateNewButtonReal = uibutton(app.AnalyzeRealTab, 'push');
            app.RotateNewButtonReal.ButtonPushedFcn = createCallbackFcn(app, @RotateNewButtonRealPushed, true);
            app.RotateNewButtonReal.Text = 'Rotate';
            app.GaussianFilterNewButtonReal = uibutton(app.AnalyzeRealTab, 'push');
            app.GaussianFilterNewButtonReal.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterNewButtonRealPushed, true);
            app.GaussianFilterNewButtonReal.Text = 'Gaussian Filter';
            app.KeepChangesNewRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.KeepChangesNewRealButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesNewRealButtonPushed, true);
            app.KeepChangesNewRealButton.Text = 'Keep Changes';
            app.ColormapRealDropDownNew = uidropdown(app.AnalyzeRealTab);
            app.ColormapRealDropDownNew.Items = {'parula', 'viridis', 'plasma', 'magma', 'inferno', 'gray', 'bone'};
            app.ColormapRealDropDownNew.ValueChangedFcn = createCallbackFcn(app, @ColormapRealDropDownNewValueChanged, true);
            app.ColormapRealDropDownNew.Value = 'parula';
            app.SaveallRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.SaveallRealButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallRealButtonPushed, true);
            app.SaveallRealButton.Text = 'Save all Real';
            app.FlattenButton = uibutton(app.AnalyzeRealTab, 'push');
            app.FlattenButton.ButtonPushedFcn = createCallbackFcn(app, @FlattenButtonPushed, true);
            app.FlattenButton.Text = 'Flatten';
            app.FlattenDropDown = uidropdown(app.AnalyzeRealTab);
            app.FlattenDropDown.Items = {'Horizontal', 'Vertical', 'Both'};
            app.FlattenDropDown.ValueChangedFcn = createCallbackFcn(app, @FlattenDropDownValueChanged, true);
            app.FlattenDropDown.Value = 'Horizontal';
            app.PlaneButton = uibutton(app.AnalyzeRealTab, 'push');
            app.PlaneButton.ButtonPushedFcn = createCallbackFcn(app, @PlaneButtonPushed, true);
            app.PlaneButton.Text = 'Plane';
            app.SquareFilterButton = uibutton(app.AnalyzeRealTab, 'push');
            app.SquareFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SquareFilterButtonPushed, true);
            app.SquareFilterButton.Text = 'Square Filter';
            app.SquareSpinner = uispinner(app.AnalyzeRealTab);
            app.SquareSpinner.Limits = [1 Inf];
            app.SquareSpinner.Value = 1;
            app.ReverseColormapRealCheckBox = uicheckbox(app.AnalyzeRealTab);
            app.ReverseColormapRealCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseColormapRealCheckBoxValueChanged, true);
            app.ReverseColormapRealCheckBox.Text = 'Reverse';
            app.HistogramButton = uibutton(app.AnalyzeRealTab, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.AngleCorrelationButton = uibutton(app.AnalyzeRealTab, 'push');
            app.AngleCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @AngleCorrelationButtonPushed, true);
            app.AngleCorrelationButton.Text = 'Angle Correlation';
            app.SaveAllRealDropDown = uidropdown(app.AnalyzeRealTab);
            app.SaveAllRealDropDown.Items = {'Title and axes', 'Only title', 'No title, no axes'};
            app.SaveAllRealDropDown.ItemsData = {'2', '1', '0'};
            app.SaveAllRealDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveAllRealDropDownValueChanged, true);
            app.SaveAllRealDropDown.Value = '2';
            app.ColormapRealDropDownNewCategory = uidropdown(app.AnalyzeRealTab);
            app.ColormapRealDropDownNewCategory.Items = {'Most Used', 'Sequential', 'Diverging', 'Cyclic', 'Misc', 'Categorical', 'Uniform Sequential', 'Uniform Diverging', 'Uniform Cyclic'};
            app.ColormapRealDropDownNewCategory.ValueChangedFcn = createCallbackFcn(app, @ColormapRealDropDownNewCategoryValueChanged, true);
            app.ColormapRealDropDownNewCategory.Value = 'Most Used';
            app.ShiftColormapRealCheckBox = uicheckbox(app.AnalyzeRealTab);
            app.ShiftColormapRealCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShiftColormapRealCheckBoxValueChanged, true);
            app.ShiftColormapRealCheckBox.Enable = 'off';
            app.ShiftColormapRealCheckBox.Visible = 'off';
            app.ShiftColormapRealCheckBox.Text = 'Shift';
            app.AnalyzeFFTTab = uitab(app.TabGroup);
            app.AnalyzeFFTTab.Title = 'Analyze FFT';
            app.EditRotateAngleFFT = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.EditGaussianFilterFFT = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.AngleprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.AngleprofileButton.Enable = 'off';
            app.AngleprofileButton.Visible = 'off';
            app.AngleprofileButton.Text = 'Angle profile';
            app.EditProfileAngle = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.EditProfileAngle.Editable = 'off';
            app.EditProfileAngle.Visible = 'off';
            app.RadialprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.RadialprofileButton.Enable = 'off';
            app.RadialprofileButton.Visible = 'off';
            app.RadialprofileButton.Text = 'Radial profile';
            app.VerticalprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.VerticalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalprofileButtonPushed, true);
            app.VerticalprofileButton.Text = 'Vertical profile';
            app.HorizontalprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.HorizontalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalprofileButtonPushed, true);
            app.HorizontalprofileButton.Text = 'Horizontal profile';
            app.DiagonalprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.DiagonalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DiagonalprofileButtonPushed, true);
            app.DiagonalprofileButton.Text = 'Diagonal profile';
            app.SaveContrastFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.SaveContrastFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveContrastFFTButtonPushed, true);
            app.SaveContrastFFTButton.Text = 'Save Contrast';
            app.LoadContrastFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.LoadContrastFFTButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContrastFFTButtonPushed, true);
            app.LoadContrastFFTButton.Text = 'Load Contrast';
            app.CutasviewButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.CutasviewButton.ButtonPushedFcn = createCallbackFcn(app, @CutasviewButtonPushed, true);
            app.CutasviewButton.Text = 'Cut as view';
            app.KeepChangesNewFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.KeepChangesNewFFTButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesNewFFTButtonPushed, true);
            app.KeepChangesNewFFTButton.Text = 'Keep Changes';
            app.FFTMapsButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.FFTMapsButton.ButtonPushedFcn = createCallbackFcn(app, @FFTMapsButtonPushed2, true);
            app.FFTMapsButton.Text = 'FFT Maps';
            app.ColormapFFTDropDownNew = uidropdown(app.AnalyzeFFTTab);
            app.ColormapFFTDropDownNew.Items = {'parula', 'viridis', 'plasma', 'magma', 'inferno', 'gray', 'bone'};
            app.ColormapFFTDropDownNew.ValueChangedFcn = createCallbackFcn(app, @ColormapFFTDropDownNewValueChanged, true);
            app.ColormapFFTDropDownNew.Value = 'parula';
            app.VisualizeFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.VisualizeFFTButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeFFTButtonPushed2, true);
            app.VisualizeFFTButton.Text = 'Visualize FFT';
            app.SaveallFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.SaveallFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallFFTButtonPushed2, true);
            app.SaveallFFTButton.Text = 'Save all FFT';
            app.AdjustZeroButton_2 = uibutton(app.AnalyzeFFTTab, 'push');
            app.AdjustZeroButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdjustZeroButton_2Pushed, true);
            app.AdjustZeroButton_2.Text = 'Adjust (0,0)';
            app.RemoveCentralLineButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.RemoveCentralLineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCentralLineButtonPushed2, true);
            app.RemoveCentralLineButton.Text = 'Remove Central Line';
            app.RotateNewButtonFFT = uibutton(app.AnalyzeFFTTab, 'push');
            app.RotateNewButtonFFT.ButtonPushedFcn = createCallbackFcn(app, @RotateNewButtonFFTPushed, true);
            app.RotateNewButtonFFT.Text = 'Rotate';
            app.GaussianFilterNewButtonFFT = uibutton(app.AnalyzeFFTTab, 'push');
            app.GaussianFilterNewButtonFFT.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterNewButtonFFTPushed, true);
            app.GaussianFilterNewButtonFFT.Text = 'Gaussian Filter';
            app.SymmetrizeButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.SymmetrizeButton.ButtonPushedFcn = createCallbackFcn(app, @SymmetrizeButtonPushed2, true);
            app.SymmetrizeButton.Text = 'Symmetrize';
            app.SquareFilterFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.SquareFilterFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SquareFilterFFTButtonPushed, true);
            app.SquareFilterFFTButton.Text = 'Square Filter';
            app.SquareSpinnerFFT = uispinner(app.AnalyzeFFTTab);
            app.SquareSpinnerFFT.Limits = [1 Inf];
            app.SquareSpinnerFFT.Value = 1;
            app.GaussiancoresubstractionButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.GaussiancoresubstractionButton.ButtonPushedFcn = createCallbackFcn(app, @GaussiancoresubstractionButtonPushed, true);
            app.GaussiancoresubstractionButton.Text = {'Gaussian core'; 'substraction'};
            app.SigmaEditField_2Label = uilabel(app.AnalyzeFFTTab);
            app.SigmaEditField_2Label.Text = 'Sigma';
            app.SigmaCoreEditField = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.SigmaCoreEditField.Limits = [0.0001 Inf];
            app.SigmaCoreEditField.Value = 1;
            app.ReverseColormapFFTCheckBox = uicheckbox(app.AnalyzeFFTTab);
            app.ReverseColormapFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseColormapFFTCheckBoxValueChanged, true);
            app.ReverseColormapFFTCheckBox.Text = 'Reverse';
            app.qanisotropyButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.qanisotropyButton.ButtonPushedFcn = createCallbackFcn(app, @qanisotropyButtonPushed, true);
            app.qanisotropyButton.Text = 'q anisotropy';
            app.qanisotropyEditField = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.qanisotropyEditField.Limits = [0 Inf];
            app.qanisotropyEditField.Value = 0.5;
            app.SymmetryDropDown = uidropdown(app.AnalyzeFFTTab);
            app.SymmetryDropDown.Items = {'C3', 'C4', 'C6', 'Vertical', 'Hor+Ver'};
            app.SymmetryDropDown.ValueChangedFcn = createCallbackFcn(app, @SymmetryDropDownValueChanged, true);
            app.SymmetryDropDown.Value = 'C4';
            app.SaveAllFFTDropDown = uidropdown(app.AnalyzeFFTTab);
            app.SaveAllFFTDropDown.Items = {'Title and axes', 'Only title', 'No title, no axes'};
            app.SaveAllFFTDropDown.ItemsData = {'2', '1', '0'};
            app.SaveAllFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveAllFFTDropDownValueChanged, true);
            app.SaveAllFFTDropDown.Value = '2';
            app.ColormapFFTDropDownNewCategory = uidropdown(app.AnalyzeFFTTab);
            app.ColormapFFTDropDownNewCategory.Items = {'Most Used', 'Sequential', 'Diverging', 'Cyclic', 'Misc', 'Categorical', 'Uniform Sequential', 'Uniform Diverging', 'Uniform Cyclic'};
            app.ColormapFFTDropDownNewCategory.ValueChangedFcn = createCallbackFcn(app, @ColormapFFTDropDownNewCategoryValueChanged, true);
            app.ColormapFFTDropDownNewCategory.Value = 'Most Used';
            app.ShiftColormapFFTCheckBox = uicheckbox(app.AnalyzeFFTTab);
            app.ShiftColormapFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShiftColormapFFTCheckBoxValueChanged, true);
            app.ShiftColormapFFTCheckBox.Enable = 'off';
            app.ShiftColormapFFTCheckBox.Visible = 'off';
            app.ShiftColormapFFTCheckBox.Text = 'Shift';
            app.WarpCorrectionTab = uitab(app.TabGroup);
            app.WarpCorrectionTab.Title = 'Warp Correction';
            app.XShearwarpEditFieldLabel = uilabel(app.WarpCorrectionTab);
            app.XShearwarpEditFieldLabel.Text = 'X Shear warp';
            app.XShearwarpEditField = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YShearwarpEditFieldLabel = uilabel(app.WarpCorrectionTab);
            app.YShearwarpEditFieldLabel.Text = 'Y Shear warp';
            app.YShearwarpEditField = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YXRatioEditFieldLabel = uilabel(app.WarpCorrectionTab);
            app.YXRatioEditFieldLabel.Text = 'Y/X Ratio';
            app.YXRatioEditField = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YXRatioEditField.Value = 1;
            app.WarpCorrectionButton = uibutton(app.WarpCorrectionTab, 'push');
            app.WarpCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @WarpCorrectionButtonPushed, true);
            app.WarpCorrectionButton.Text = 'Warp Correction';
            app.GapMappingTab = uitab(app.TabGroup);
            app.GapMappingTab.Title = 'Gap Mapping';
            app.SweepDirection = uibuttongroup(app.GapMappingTab);
            app.SweepDirection.Enable = 'off';
            app.SweepDirection.Visible = 'off';
            app.XButton = uiradiobutton(app.SweepDirection);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.SweepDirection);
            app.YButton.Text = 'Y';
            app.SweepdirectionLabel = uilabel(app.GapMappingTab);
            app.SweepdirectionLabel.Enable = 'off';
            app.SweepdirectionLabel.Visible = 'off';
            app.SweepdirectionLabel.Text = 'Sweep direction';
            app.RangePeaksmVEditFieldLabel = uilabel(app.GapMappingTab);
            app.RangePeaksmVEditFieldLabel.Text = 'Range Peaks (mV)';
            app.RangePeaksmVEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.RangePeaksmVEditField.Limits = [0 Inf];
            app.RangePeaksmVEditField.Value = 0.5;
            app.RangeMinmVEditFieldLabel = uilabel(app.GapMappingTab);
            app.RangeMinmVEditFieldLabel.Text = 'Range Min (mV)';
            app.RangeMinmVEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.RangeMinmVEditField.Limits = [0 Inf];
            app.RangeMinmVEditField.Value = 0.05;
            app.ThresholdratioEditFieldLabel = uilabel(app.GapMappingTab);
            app.ThresholdratioEditFieldLabel.Text = 'Threshold ratio';
            app.ThresholdratioEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.ThresholdratioEditField.Limits = [0 Inf];
            app.ThresholdratioEditField.Value = 1.5;
            app.CalculateGapMapButton = uibutton(app.GapMappingTab, 'push');
            app.CalculateGapMapButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateGapMapButtonPushed, true);
            app.CalculateGapMapButton.Text = 'Calculate Gap Map';
            app.GaussianfilterEditFieldLabel = uilabel(app.GapMappingTab);
            app.GaussianfilterEditFieldLabel.Text = 'Gaussian filter';
            app.GaussianfilterEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.GaussianfilterEditField.Limits = [0 Inf];
            app.GaussianfilterEditField.Value = 0.5;
            app.ContrastMinEditFieldLabel = uilabel(app.GapMappingTab);
            app.ContrastMinEditFieldLabel.Text = 'Contrast Min';
            app.ContrastMinEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.ContrastMinEditField.Limits = [0 Inf];
            app.ContrastMinEditField.Value = 0.2;
            app.ContrastMaxEditFieldLabel = uilabel(app.GapMappingTab);
            app.ContrastMaxEditFieldLabel.Text = 'Contrast Max';
            app.ContrastMaxEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.ContrastMaxEditField.Limits = [0 Inf];
            app.ContrastMaxEditField.Value = 0.4;
            app.GoButton = uibutton(app.GapMappingTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.MoreFunctionsTab = uitab(app.TabGroup);
            app.MoreFunctionsTab.Title = 'More Functions';
            app.AveragescatteringButton = uibutton(app.MoreFunctionsTab, 'push');
            app.AveragescatteringButton.ButtonPushedFcn = createCallbackFcn(app, @AveragescatteringButtonPushed, true);
            app.AveragescatteringButton.Text = 'Average scattering';
            app.PopcornButton = uibutton(app.MoreFunctionsTab, 'push');
            app.PopcornButton.ButtonPushedFcn = createCallbackFcn(app, @PopcornButtonPushed, true);
            app.PopcornButton.Text = 'Popcorn';
            app.SigmaEditFieldLabel = uilabel(app.MoreFunctionsTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.MoreFunctionsTab, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.SigmaEditField.Value = 4;
            app.SymmetryTab = uitab(app.TabGroup);
            app.SymmetryTab.Title = 'Symmetry';
            app.EnergySymmetryButton = uibutton(app.SymmetryTab, 'push');
            app.EnergySymmetryButton.ButtonPushedFcn = createCallbackFcn(app, @EnergySymmetryButtonPushed, true);
            app.EnergySymmetryButton.Text = 'Energy Symmetry';
            app.SaveContrastRestasButton = uibutton(app.SymmetryTab, 'push');
            app.SaveContrastRestasButton.Text = 'Save Contrast';
            app.LoadContrastRestasButton = uibutton(app.SymmetryTab, 'push');
            app.LoadContrastRestasButton.Text = 'Load Contrast';
            app.ColormapRestasDropDown = uidropdown(app.SymmetryTab);
            app.ColormapRestasDropDown.Items = {'parula', 'viridis', 'plasma', 'magma', 'inferno', 'gray', 'bone'};
            app.ColormapRestasDropDown.Value = 'parula';
            app.ReverseColormapRestasCheckBox = uicheckbox(app.SymmetryTab);
            app.ReverseColormapRestasCheckBox.Text = 'Reverse';
            app.ColormapRestasDropDownCategory = uidropdown(app.SymmetryTab);
            app.ColormapRestasDropDownCategory.Items = {'Most Used', 'Sequential', 'Diverging', 'Cyclic', 'Misc', 'Categorical', 'Uniform Sequential', 'Uniform Diverging', 'Uniform Cyclic'};
            app.ColormapRestasDropDownCategory.Value = 'Most Used';
            app.ShiftColormapRestasCheckBox = uicheckbox(app.SymmetryTab);
            app.ShiftColormapRestasCheckBox.Enable = 'off';
            app.ShiftColormapRestasCheckBox.Visible = 'off';
            app.ShiftColormapRestasCheckBox.Text = 'Shift';
            app.blqAnalysisQPIStudyLabel = uilabel(app.blqAnalysisUIFigure);
            app.blqAnalysisQPIStudyLabel.Text = 'blqAnalysis + QPI Study';
            app.TemperatureLabel = uilabel(app.blqAnalysisUIFigure);
            app.TemperatureLabel.Visible = 'off';
            app.TemperatureLabel.Text = 'T = ';
            app.MagneticFieldLabel = uilabel(app.blqAnalysisUIFigure);
            app.MagneticFieldLabel.Visible = 'off';
            app.MagneticFieldLabel.Text = 'H = ';
            app.TemperatureValue = uilabel(app.blqAnalysisUIFigure);
            app.TemperatureValue.Visible = 'off';
            app.TemperatureValue.Text = '';
            app.MagneticFieldValue = uilabel(app.blqAnalysisUIFigure);
            app.MagneticFieldValue.Visible = 'off';
            app.MagneticFieldValue.Text = '';
            % Show the figure after all components are created
            app.blqAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blqApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.blqAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.blqAnalysisUIFigure)
        end
    end
end